classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        tiempo              matlab.ui.control.Spinner
        TiempoSpinnerLabel  matlab.ui.control.Label
        lpmGauge            matlab.ui.control.Gauge
        lpmLabel            matlab.ui.control.Label
        Predeterminados     matlab.ui.container.ButtonGroup
        Bradicardia         matlab.ui.control.RadioButton
        Normal              matlab.ui.control.RadioButton
        Taquicardia         matlab.ui.control.RadioButton
        Lamp                matlab.ui.control.Lamp
        Switch              matlab.ui.control.Switch
        RightPanel          matlab.ui.container.Panel
        axes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            format short
            global v3 m line t a
            if strcmp(app.Switch.Value, 'Off')
                app.Lamp.Color = [0.5 0.5 0.5];
                app.Predeterminados.Enable = 'Off';
                app.axes.Title.String = "La interfaz está apagada";
                cla(app.axes)
                t = 0;
            else
                app.Lamp.Color = [0 1 0];
                app.Predeterminados.Enable = 'On';
                app.axes.Title.String = "Señal de salida";
                load("ecgV.mat","out")
                v3 = out(:,9);
                xlim(app.axes,[0 app.tiempo.Value])
                ylim(app.axes,[min(v3)-1 max(v3)+1])
                m=0; a = 0;
                line = animatedline(app.axes);
            end
        end

        % Selection changed function: Predeterminados
        function PredeterminadosSelectionChanged(app, event)
            % comandos de "primera vez"
            global v3 line t a %g offset
            lpm = randi([60 80],1);
            l = 60/lpm;
            i = 1;
            tm1 = linspace(0,l/4,10);
            tf = tm1(end)+linspace(0,1/3,60);
            tm2 = tf(end)+linspace(0,3*l/4,30);
            t = [tm1 tf tm2];
            
            % Ciclo de la función
            while strcmp(app.Switch.Value, 'On') && a<=app.tiempo.Value %&& ~app.EntradaA.Value
                
                % En caso de acabarse los datos
                if i == 100
                    p = t(v3==max(findpeaks(v3)));
                    tp = p-a-1/3;
                    xline(app.axes,p,'--r',{"\Deltat = "+string(tp)+"s"})
                    % Revisamos si no hay cambios en el ritmo.
                    if ~app.Predeterminados.SelectedObject.Value
                        % Si los hay ¿Cúal es el nuevo ritmo?
                        if  app.Bradicardia.Value
                            lpm = randi([120 180],1);
                            app.Predeterminados.SelectedObject= app.Bradicardia;
                        elseif app.Normal.Value
                            lpm = randi([60 80],1);
                            app.Predeterminados.SelectedObject = app.Normal;
                        elseif app.Taquicardia.Value 
                            lpm = randi([0 40],1);
                            app.Predeterminados.SelectedObject = app.Taquicardia;
                        end
                        l = 60/lpm;
                        tm1 = linspace(0,l/4,10);
                        tf = tm1(end)+linspace(0,1/3,60);
                        tm2 = tf(end)+linspace(0,3*l/4,30);
                        t = [tm1 tf tm2]+t(end);
                        disp("---cambio---")
                        % Sin cambios, volvemos a empezar
                    else
                        t = t+tm2(end);
                        app.lpmGauge.Value = 60/tp;
                        app.lpmLabel.Text = "lpm = " + string(app.lpmGauge.Value);
                        disp("---constante---")
                    end
                    i = 1;
                    a = p;
                end
                addpoints(line,t(i),v3(i))
                % v(i) = readVoltage(ard,"A0") 
                drawnow
                i = i+1;
            end
            xlim(app.axes,[0 app.tiempo.Value])
            disp("Fin")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {392, 392};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.LeftPanel);
            app.Predeterminados = uibuttongroup(app.LeftPanel);
            app.Predeterminados.SelectionChangedFcn = createCallbackFcn(app, @PredeterminadosSelectionChanged, true);
            app.Predeterminados.Enable = 'off';
            app.Predeterminados.Title = 'Predeterminados';
            app.Taquicardia = uiradiobutton(app.Predeterminados);
            app.Taquicardia.Text = 'Bradicardia (<40 lpm)';
            app.Taquicardia.Value = true;
            app.Normal = uiradiobutton(app.Predeterminados);
            app.Normal.Text = 'Normal (60 a 80 lpm)';
            app.Bradicardia = uiradiobutton(app.Predeterminados);
            app.Bradicardia.Text = 'Taquicardia (>120 lpm)';
            app.lpmLabel = uilabel(app.LeftPanel);
            app.lpmLabel.Text = 'lpm';
            app.lpmGauge = uigauge(app.LeftPanel, 'circular');
            app.lpmGauge.Limits = [0 180];
            app.TiempoSpinnerLabel = uilabel(app.LeftPanel);
            app.TiempoSpinnerLabel.Text = 'Tiempo';
            app.tiempo = uispinner(app.LeftPanel);
            app.tiempo.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.axes = uiaxes(app.RightPanel);
            title(app.axes, 'Bienvenido')
            xlabel(app.axes, 'Tiempo [s]')
            ylabel(app.axes, 'Voltaje [V]')
            zlabel(app.axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end